classdef App_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        INTERFAZ                        matlab.ui.container.Tab
        GUARDARPanel_2                  matlab.ui.container.Panel
        GUARDARmatButton                matlab.ui.control.Button
        GuardarParametrosdeFiltroLabel  matlab.ui.control.Label
        GuardarsealCargadayFiltradaLabel  matlab.ui.control.Label
        GUARDARcsvButton                matlab.ui.control.Button
        RESPUESTAENFRECUENCIAPanel      matlab.ui.container.Panel
        FrecuenciaButtonGroup           matlab.ui.container.ButtonGroup
        HzButton                        matlab.ui.control.RadioButton
        radmuestraButton                matlab.ui.control.RadioButton
        MagnitudButtonGroup             matlab.ui.container.ButtonGroup
        NoendBButton                    matlab.ui.control.RadioButton
        EndBButton                      matlab.ui.control.RadioButton
        RFGrafica_3                     matlab.ui.control.UIAxes
        RFGrafica                       matlab.ui.control.UIAxes
        GRFICASPanel                    matlab.ui.container.Panel
        VisualizacinButtonGroup         matlab.ui.container.ButtonGroup
        StemButton                      matlab.ui.control.RadioButton
        PlotButton                      matlab.ui.control.RadioButton
        UIAxes_Original                 matlab.ui.control.UIAxes
        UIAxes_Filtrada                 matlab.ui.control.UIAxes
        UIAxes_OriginalSp               matlab.ui.control.UIAxes
        UIAxes_FiltradaSp               matlab.ui.control.UIAxes
        SEALDEENTRADAPanel              matlab.ui.container.Panel
        TiempoEditField                 matlab.ui.control.NumericEditField
        TiempoEditFieldLabel            matlab.ui.control.Label
        segLabel                        matlab.ui.control.Label
        DebetenervariablesFsyxLabel     matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        Switch                          matlab.ui.control.Switch
        Label                           matlab.ui.control.Label
        DeTivaCLabel                    matlab.ui.control.Label
        CAPTURARButton                  matlab.ui.control.Button
        CargararchivomatLabel           matlab.ui.control.Label
        SELECCIONARButton               matlab.ui.control.Button
        DISEODELFILTROPanel             matlab.ui.container.Panel
        FILTRARButton                   matlab.ui.control.Button
        VentanaDropDown                 matlab.ui.control.DropDown
        VentanaDropDownLabel            matlab.ui.control.Label
        dBLabel_2                       matlab.ui.control.Label
        dBLabel                         matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        PassbandRippleRpEditField       matlab.ui.control.NumericEditField
        PassbandRippleRpEditFieldLabel  matlab.ui.control.Label
        StopbandAttenuationRsEditField  matlab.ui.control.NumericEditField
        StopbandAttenuationRsEditFieldLabel  matlab.ui.control.Label
        FrecuenciadeCorteAltaEditField  matlab.ui.control.NumericEditField
        FrecuenciadeCorteAltaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadeCorteBajaEditField  matlab.ui.control.NumericEditField
        FrecuenciadeCorteBajaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadeCorteEditFieldLabel  matlab.ui.control.Label
        FrecuenciadeCorteEditField      matlab.ui.control.NumericEditField
        ClaseDropDown                   matlab.ui.control.DropDown
        ClaseDropDownLabel              matlab.ui.control.Label
        SubtipoDropDown                 matlab.ui.control.DropDown
        SubtipoDropDownLabel            matlab.ui.control.Label
        TipoDropDown                    matlab.ui.control.DropDown
        TipoDropDownLabel               matlab.ui.control.Label
        OrdenSpinner                    matlab.ui.control.Spinner
        OrdenSpinnerLabel               matlab.ui.control.Label
        TIVAC                           matlab.ui.container.Tab
        COEFICIENTESPanel               matlab.ui.container.Panel
        bEditField                      matlab.ui.control.EditField
        bEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.EditField
        aEditFieldLabel                 matlab.ui.control.Label
        RESPUESTAENFRECUENCIAPanel_2    matlab.ui.container.Panel
        FrecuenciaButtonGroup_2         matlab.ui.container.ButtonGroup
        HzButton_2                      matlab.ui.control.RadioButton
        radmuestraButton_2              matlab.ui.control.RadioButton
        MagnitudButtonGroup_2           matlab.ui.container.ButtonGroup
        NoendBButton_2                  matlab.ui.control.RadioButton
        EndBButton_2                    matlab.ui.control.RadioButton
        RFGrafica_4                     matlab.ui.control.UIAxes
        RFGrafica_2                     matlab.ui.control.UIAxes
        DISEODELFILTROPanel_2           matlab.ui.container.Panel
        OrdenButtonGroup                matlab.ui.container.ButtonGroup
        Button_4                        matlab.ui.control.RadioButton
        Button_3                        matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button                          matlab.ui.control.RadioButton
        nicamenteTipodeFiltroIIRLabel   matlab.ui.control.Label
        ENVIARATIVACButton              matlab.ui.control.Button
        dBLabel_4                       matlab.ui.control.Label
        dBLabel_3                       matlab.ui.control.Label
        HzLabel_6                       matlab.ui.control.Label
        HzLabel_5                       matlab.ui.control.Label
        HzLabel_4                       matlab.ui.control.Label
        PassbandRippleRpEditField_2     matlab.ui.control.NumericEditField
        PassbandRippleRpEditField_2Label  matlab.ui.control.Label
        StopbandAttenuationRsEditField_2  matlab.ui.control.NumericEditField
        StopbandAttenuationRsEditField_2Label  matlab.ui.control.Label
        FrecuenciadeCorteAltaEditField_2  matlab.ui.control.NumericEditField
        FrecuenciadeCorteAltaEditField_2Label  matlab.ui.control.Label
        FrecuenciadeCorteBajaEditField_2  matlab.ui.control.NumericEditField
        FrecuenciadeCorteBajaEditField_2Label  matlab.ui.control.Label
        FrecuenciadeCorteEditField_2Label  matlab.ui.control.Label
        FrecuenciadeCorteEditField_2    matlab.ui.control.NumericEditField
        ClaseDropDown_2                 matlab.ui.control.DropDown
        ClaseDropDown_2Label            matlab.ui.control.Label
        SubtipoDropDown_2               matlab.ui.control.DropDown
        SubtipoDropDown_2Label          matlab.ui.control.Label
        CONEXINCONTIVACPanel            matlab.ui.container.Panel
        CONECTARATIVACButton            matlab.ui.control.Button
        ConexinEstablecidaLabel         matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        GUARDARPanel_3                  matlab.ui.container.Panel
        GUARDARmatButton_2              matlab.ui.control.Button
        GuardarParametrosdeFiltroLabel_2  matlab.ui.control.Label
    end

    
    properties (Access = public)
                % Variables Globales

                % Para Interfaz
                Fs = 1000
                Ts = 1/1000
                TipoInterfaz = 1
                SubtipoI = 1
                ClaseI = 1
                OrdenI = 2
                Ventana = 1
                w_FIR = rectwin(3);
                
                FcI = 1 % Frecuencias de Corte
                FcLI = 50
                FcHI = 100
                FcBandI = [50 100]

                RsI = 1 
                RpI = 1

                bI = zeros(1,5);
                aI = zeros(1,5);

                % Para Tiva C
                FsTiva = 1000
                TsTiva = 1/1000
                SubtipoT = 1
                ClaseT = 1
                OrdenT = 1
        
                FcT = 1
                FcLT = 50
                FcHT = 100
                FcBandT = [50 100]
                
                RsT = 1
                RpT = 1

                aT = zeros(1,5);
                bT = zeros(1,5);
        
                s
                coeficientes = zeros(1,9);
                
                bFIR = 1
        
                MagFR = 1
                FreFR = 1

                MagFRTiva = 1
                FreFRTiva = 1
                
                %  Variables para Programacion Defensiva
                previousFcLI
                previousFcHI

                previousFcLT
                previousFcHT

                previousRsI
                previousRpI
                previousOrdenI

                previousRsT
                previousRpT
                previousOrdenT

                % Ploteo de Señales y Espectros
                t
                x
                X
                freqSp
                fil
                FIL
        
                Vis = 1
    end

    methods (Access = private)
        
        function generarFiltro(app)
            if app.TipoInterfaz == 1
                app.PassbandRippleRpEditField.Enable = 'off';
                app.StopbandAttenuationRsEditField.Enable = 'off';
                app.SubtipoDropDown.Enable = "off";
                app.VentanaDropDown.Enable = "on";
                app.OrdenSpinner.Step = 2; % Paso a 2 
    
                app.OrdenI = app.OrdenSpinner.Value; 
                app.previousOrdenI = app.OrdenSpinner.Value;
                if mod(app.OrdenSpinner.Value, 2) ~= 0
                    uialert(app.UIFigure, 'El Orden debe ser un número par', 'Error');
                    app.OrdenI = app.OrdenSpinner.Value + 1; % Asegurar que el valor sea par
                    app.OrdenSpinner.Value = app.OrdenSpinner.Value + 1;
                else
                    app.OrdenI = app.OrdenSpinner.Value;
                end

                if app.Ventana == 1
                    app.w_FIR = rectwin(app.OrdenI+1);     % Ventana rectangular
                elseif app.Ventana == 2
                    app.w_FIR = bartlett(app.OrdenI+1);    % Ventana de Bartlett
                elseif app.Ventana == 3
                    app.w_FIR = hann(app.OrdenI+1);        % Ventana de Hann
                elseif app.Ventana == 4
                    app.w_FIR = hamming(app.OrdenI+1);     % Ventana de Hamming
                elseif app.Ventana == 5
                    app.w_FIR = blackman(app.OrdenI+1);    % Ventana de Blackman
                elseif app.Ventana == 6
                    app.w_FIR = kaiser(app.OrdenI+1, 2.5); % Ventana de Kaiser
                end
    
                wc = 2*pi*app.FcI*(1/app.Fs);
                wcband = [2*pi*app.FcLI*(1/app.Fs) 2*pi*app.FcHI*(1/app.Fs)];
                if app.ClaseI == 1
                    b = fir1(app.OrdenI, wc/pi, 'low', app.w_FIR, 'noscale');  
                elseif app.ClaseI == 2
                    b = fir1(app.OrdenI, wc/pi, 'high', app.w_FIR, 'noscale');  
                elseif app.ClaseI == 3
                    b = fir1(app.OrdenI, wcband/pi, 'bandpass', app.w_FIR, 'noscale');  
                elseif app.ClaseI == 4
                    b = fir1(app.OrdenI, wcband/pi, 'stop', app.w_FIR, 'noscale');  
                end

                app.bFIR = b;
    
                % Calcular la respuesta en frecuencia
                [h, w] = freqz(b, 1, 1024); % 1024 puntos de frecuencia
                
                % Convertir la frecuencia a Hz
                f = w * (app.Fs / (2*pi));
    
                % Convertir la magnitud a dB
                H_dB = 20 * log10(abs(h));
    
                if app.MagFR == 1
                    if app.FreFR == 1
                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica);
                
                        plot(app.RFGrafica, w, H_dB, 'Color', '#752642');
                        xlim(app.RFGrafica, [w(1), w(end)]);
                        xlabel(app.RFGrafica, '\omega (rad/muestra)');
                        ylabel(app.RFGrafica, 'Magnitud en dB');
                        grid(app.RFGrafica, 'on');

                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica_3);
                
                        plot(app.RFGrafica_3, w, angle(h), 'Color', '#752642');
                        xlim(app.RFGrafica_3, [w(1), w(end)]);
                        xlabel(app.RFGrafica_3, '\omega (rad/muestra)');
                        ylabel(app.RFGrafica_3, 'Fase (rad)');
                        grid(app.RFGrafica_3, 'on');
                    elseif app.FreFR == 2
                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica);
                
                        plot(app.RFGrafica, f, H_dB, 'Color', '#752642');
                        xlim(app.RFGrafica, [f(1), f(end)]);
                        xlabel(app.RFGrafica, 'f (Hz)');
                        ylabel(app.RFGrafica, 'Magnitud en dB');
                        grid(app.RFGrafica, 'on');

                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica_3);
                
                        plot(app.RFGrafica_3, f, angle(h), 'Color', '#752642');
                        xlim(app.RFGrafica_3, [f(1), f(end)]);
                        xlabel(app.RFGrafica_3, '\omega (rad/muestra)');
                        ylabel(app.RFGrafica_3, 'Fase (rad)');
                        grid(app.RFGrafica_3, 'on');
                    end
                elseif app.MagFR == 2
                    if app.FreFR == 1
                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica);
               
                        plot(app.RFGrafica, w, abs(h), 'Color', '#752642');
                        xlim(app.RFGrafica, [w(1), w(end)]);
                        xlabel(app.RFGrafica, '\omega (rad/muestra)');
                        ylabel(app.RFGrafica, 'Magnitud');
                        grid(app.RFGrafica, 'on');

                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica_3);
                
                        plot(app.RFGrafica_3, w, angle(h), 'Color', '#752642');
                        xlim(app.RFGrafica_3, [w(1), w(end)]);
                        xlabel(app.RFGrafica_3, '\omega (rad/muestra)');
                        ylabel(app.RFGrafica_3, 'Fase (rad)');
                        grid(app.RFGrafica_3, 'on');
                    elseif app.FreFR == 2
                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica);

                        plot(app.RFGrafica, f, abs(h), 'Color', '#752642');
                        xlim(app.RFGrafica, [f(1), f(end)]);
                        xlabel(app.RFGrafica, 'f (Hz)');
                        ylabel(app.RFGrafica, 'Magnitud');
                        grid(app.RFGrafica, 'on');

                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica_3);
                
                        plot(app.RFGrafica_3, f, angle(h), 'Color', '#752642');
                        xlim(app.RFGrafica_3, [f(1), f(end)]);
                        xlabel(app.RFGrafica_3, 'f (Hz)');
                        ylabel(app.RFGrafica_3, 'Fase (rad)');
                        grid(app.RFGrafica_3, 'on');
                    end
                end
              
        
            elseif app.TipoInterfaz == 2
                app.SubtipoDropDown.Enable = "on";
                app.VentanaDropDown.Enable = "off";

                if app.SubtipoI == 1
                    if app.ClaseI == 1
                        [app.bI, app.aI] = butter(app.OrdenI, 2*app.FcI*app.Ts, 'low');
                        app.OrdenSpinner.Step = 1; % Paso a 1
                    elseif app.ClaseI == 2
                        [app.bI, app.aI] = butter(app.OrdenI, 2*app.FcI*app.Ts, 'high');
                        app.OrdenSpinner.Step = 1; % Paso a 1
                    elseif app.ClaseI == 3
                        [app.bI, app.aI] = butter(app.OrdenI/2, 2*app.FcBandI*app.Ts,'bandpass');
                        app.OrdenSpinner.Step = 2; % Paso a 2
                    elseif app.ClaseI == 4
                        [app.bI, app.aI] = butter(app.OrdenI/2, 2*app.FcBandI*app.Ts,'stop');
                        app.OrdenSpinner.Step = 2; % Paso a 2
                    end
                elseif app.SubtipoI == 2
                    if app.ClaseI == 1
                        [app.bI, app.aI] = cheby1(app.OrdenI, app.RpI, 2*app.FcI*app.Ts,'low');
                        app.OrdenSpinner.Step = 1; % Paso a 1
                    elseif app.ClaseI == 2
                        [app.bI, app.aI] = cheby1(app.OrdenI, app.RpI, 2*app.FcI*app.Ts,'high');
                        app.OrdenSpinner.Step = 1; % Paso a 1
                    elseif app.ClaseI == 3
                        [app.bI, app.aI] = cheby1(app.OrdenI/2, app.RpI, 2*app.FcBandI*app.Ts,'bandpass');
                        app.OrdenSpinner.Step = 2; % Paso a 2
                    elseif app.ClaseI == 4
                        [app.bI, app.aI] = cheby1(app.OrdenI/2, app.RpI, 2*app.FcBandI*app.Ts,'stop');
                        app.OrdenSpinner.Step = 2; % Paso a 2
                    end
                elseif app.SubtipoI == 3
                    if app.ClaseI == 1
                        [app.bI, app.aI] = cheby2(app.OrdenI, app.RsI, 2*app.FcI*app.Ts,'low');
                        app.OrdenSpinner.Step = 1; % Paso a 1
                    elseif app.ClaseI == 2
                        [app.bI, app.aI] = cheby2(app.OrdenI, app.RsI, 2*app.FcI*app.Ts,'high');
                        app.OrdenSpinner.Step = 1; % Paso a 1
                    elseif app.ClaseI == 3
                        [app.bI, app.aI] = cheby2(app.OrdenI/2, app.RsI, 2*app.FcBandI*app.Ts,'bandpass');
                        app.OrdenSpinner.Step = 2; % Paso a 2
                    elseif app.ClaseI == 4
                        [app.bI, app.aI] = cheby2(app.OrdenI/2, app.RsI, 2*app.FcBandI*app.Ts,'stop');
                        app.OrdenSpinner.Step = 2; % Paso a 2
                    end
                end
    
                % Respuesta en frecuencia
                [h, w] = freqz(app.bI, app.aI);
                f_lpf = w*app.Fs/(2*pi);  % vector de frecuencia, en Hz
        
                % Magnitud en dB
                mag_dB = 20 * log10(abs(h));
        
                if app.MagFR == 1
                    if app.FreFR == 1
                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica);
                
                        plot(app.RFGrafica, w, mag_dB, 'Color', '#752642');
                        xlim(app.RFGrafica, [w(1), w(end)]);
                        xlabel(app.RFGrafica, '\omega (rad/muestra)');
                        ylabel(app.RFGrafica, 'Magnitud en dB');
                        grid(app.RFGrafica, 'on');

                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica_3);
                
                        plot(app.RFGrafica_3, w, angle(h), 'Color', '#752642');
                        xlim(app.RFGrafica_3, [w(1), w(end)]);
                        xlabel(app.RFGrafica_3, '\omega (rad/muestra)');
                        ylabel(app.RFGrafica_3, 'Fase (rad)');
                        grid(app.RFGrafica_3, 'on');
                    elseif app.FreFR == 2
                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica);

                        plot(app.RFGrafica, f_lpf, mag_dB, 'Color', '#752642');
                        xlim(app.RFGrafica, [f_lpf(1), f_lpf(end)]);
                        xlabel(app.RFGrafica, 'f (Hz)');
                        ylabel(app.RFGrafica, 'Magnitud en dB');
                        grid(app.RFGrafica, 'on');

                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica_3);
                
                        plot(app.RFGrafica_3, f_lpf, angle(h), 'Color', '#752642');
                        xlim(app.RFGrafica_3, [f_lpf(1), f_lpf(end)]);
                        xlabel(app.RFGrafica_3, '\omega (rad/muestra)');
                        ylabel(app.RFGrafica_3, 'Fase (rad)');
                        grid(app.RFGrafica_3, 'on');
                    end
                elseif app.MagFR == 2
                    if app.FreFR == 1
                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica);
               
                        plot(app.RFGrafica, w, abs(h), 'Color', '#752642');
                        xlim(app.RFGrafica, [w(1), w(end)]);
                        xlabel(app.RFGrafica, '\omega (rad/muestra)');
                        ylabel(app.RFGrafica, 'Magnitud');
                        grid(app.RFGrafica, 'on');

                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica_3);
                
                        plot(app.RFGrafica_3, w, angle(h), 'Color', '#752642');
                        xlim(app.RFGrafica_3, [w(1), w(end)]);
                        xlabel(app.RFGrafica_3, '\omega (rad/muestra)');
                        ylabel(app.RFGrafica_3, 'Fase (rad)');
                        grid(app.RFGrafica_3, 'on');
                    elseif app.FreFR == 2
                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica);
        
                        plot(app.RFGrafica, f_lpf, abs(h), 'Color', '#752642');
                        xlim(app.RFGrafica, [f_lpf(1), f_lpf(end)]);
                        xlabel(app.RFGrafica, 'f (Hz)');
                        ylabel(app.RFGrafica, 'Magnitud');
                        grid(app.RFGrafica, 'on');

                        % Limpiar el gráfico antes de dibujar
                        cla(app.RFGrafica_3);
                
                        plot(app.RFGrafica_3, f_lpf, angle(h), 'Color', '#752642');
                        xlim(app.RFGrafica_3, [f_lpf(1), f_lpf(end)]);
                        xlabel(app.RFGrafica_3, 'f (Hz)');
                        ylabel(app.RFGrafica_3, 'Fase (rad)');
                        grid(app.RFGrafica_3, 'on');
                    end
                end
            end
        end
        
        function filtradoMAT(app)
            if app.TipoInterfaz == 1
                app.fil = filter(app.bFIR, 1, app.x);
            elseif app.TipoInterfaz == 2
                app.fil = filter(app.bI,app.aI, app.x);
            end

           % Calcular el tiempo
            tiempo = (0:length(app.x)-1) * (1/app.Fs);
            app.t = tiempo;

            % Limpiar el gráfico antes de dibujar
            cla(app.UIAxes_Filtrada);
            
            % Graficar x vs tiempo en UIAxes_Original
            plot(app.UIAxes_Filtrada, tiempo, app.fil, 'Color', '#752642');
            xlabel(app.UIAxes_Filtrada, 'Tiempo (s)');
            ylabel(app.UIAxes_Filtrada, 'Magnitud');
            title(app.UIAxes_Filtrada, 'Señal Filtrada');
            grid(app.UIAxes_Filtrada, 'on');

            % Actualizar el límite del eje x y y
            xlim(app.UIAxes_Filtrada, [min(tiempo), max(tiempo)]);

            app.FIL = fft(app.fil);
            app.freqSp = app.Fs*(0:length(app.fil)-1)/length(app.fil);

            if app.Vis == 1
                % Limpiar el gráfico antes de dibujar
                cla(app.UIAxes_FiltradaSp);
                
                % Graficar x vs tiempo en UIAxes_Original
                plot(app.UIAxes_FiltradaSp, app.freqSp, abs(app.FIL), 'Color', '#752642');
                ylim(app.UIAxes_FiltradaSp, [min(abs(app.X)), max(abs(app.X))]);
                xlabel(app.UIAxes_FiltradaSp, 'Frecuencia (Hz)');
                ylabel(app.UIAxes_FiltradaSp, 'Magnitud');
                title(app.UIAxes_FiltradaSp, 'Espectro de la Señal Filtrada');
                grid(app.UIAxes_FiltradaSp, 'on');
            elseif app.Vis == 2
                % Limpiar el gráfico antes de dibujar
                cla(app.UIAxes_FiltradaSp);
                
                % Graficar x vs tiempo en UIAxes_Original
                stem(app.UIAxes_FiltradaSp, app.freqSp, abs(app.FIL), 'Color', '#752642');
                ylim(app.UIAxes_FiltradaSp, [min(abs(app.X)), max(abs(app.X))]);
                xlabel(app.UIAxes_FiltradaSp, 'Frecuencia (Hz)');
                ylabel(app.UIAxes_FiltradaSp, 'Magnitud');
                title(app.UIAxes_FiltradaSp, 'Espectro de la Señal Filtrada');
                grid(app.UIAxes_FiltradaSp, 'on');
            end
        end
        
        function originalMAt(app)
            % Calcular el tiempo
            tiempo = (0:length(app.x)-1) * (1/app.Fs);
            app.t = tiempo;

            % Limpiar el gráfico antes de dibujar
            cla(app.UIAxes_Original);
            
            % Graficar x vs tiempo en UIAxes_Original
            plot(app.UIAxes_Original, tiempo, app.x, 'Color', '#752642');
            xlabel(app.UIAxes_Original, 'Tiempo (s)');
            ylabel(app.UIAxes_Original, 'Magnitud');
            title(app.UIAxes_Original, 'Señal Original');
            grid(app.UIAxes_Original, 'on');
            
            % Actualizar el límite del eje x
            xlim(app.UIAxes_Original, [min(tiempo), max(tiempo)]);
            
            app.X = fft(app.x);
            app.freqSp = app.Fs*(0:length(app.x)-1)/length(app.x);

            if app.Vis == 1
                % Limpiar el gráfico antes de dibujar
                cla(app.UIAxes_OriginalSp);
                
                % Graficar x vs tiempo en UIAxes_Original
                plot(app.UIAxes_OriginalSp, app.freqSp, abs(app.X), 'Color', '#752642');
                ylim(app.UIAxes_OriginalSp, [min(abs(app.X)), max(abs(app.X))]);
                xlabel(app.UIAxes_OriginalSp, 'Frecuencia (Hz)');
                ylabel(app.UIAxes_OriginalSp, 'Magnitud');
                title(app.UIAxes_OriginalSp, 'Espectro de la Señal Original');
                grid(app.UIAxes_OriginalSp, 'on');
            elseif app.Vis == 2
                % Limpiar el gráfico antes de dibujar
                cla(app.UIAxes_OriginalSp);
                
                % Graficar x vs tiempo en UIAxes_Original
                stem(app.UIAxes_OriginalSp, app.freqSp, abs(app.X), 'Color', '#752642');
                ylim(app.UIAxes_OriginalSp, [min(abs(app.X)), max(abs(app.X))]);
                xlabel(app.UIAxes_OriginalSp, 'Frecuencia (Hz)');
                ylabel(app.UIAxes_OriginalSp, 'Magnitud');
                title(app.UIAxes_OriginalSp, 'Espectro de la Señal Original');
                grid(app.UIAxes_OriginalSp, 'on');
            end
        end
        
        function FiltroTiva(app)
            if app.ClaseT == 3 || app.ClaseT == 4
                if mod(app.OrdenT, 2) ~= 0
                    uialert(app.UIFigure, 'El Orden debe ser un número par', 'Error');
                    app.OrdenButtonGroup.SelectedObject = app.Button_2;
                    app.OrdenT = 2;
                end
            end

            if app.SubtipoT == 1
                if app.ClaseT == 1
                    [app.bT, app.aT] = butter(app.OrdenT, 2*app.FcT*app.TsTiva, 'low');
                elseif app.ClaseT == 2
                    [app.bT, app.aT] = butter(app.OrdenT, 2*app.FcT*app.TsTiva, 'high');
                elseif app.ClaseT == 3
                    [app.bT, app.aT] = butter(app.OrdenT/2, 2*app.FcBandT*app.TsTiva,'bandpass' );
                elseif app.ClaseT == 4
                    [app.bT, app.aT] = butter(app.OrdenT/2, 2*app.FcBandT*app.TsTiva,'stop');
                end
            elseif app.SubtipoT == 2
                if app.ClaseT == 1
                    [app.bT, app.aT] = cheby1(app.OrdenT, app.RpT, 2*app.FcT*app.TsTiva,'low');
                elseif app.ClaseT == 2
                    [app.bT, app.aT] = cheby1(app.OrdenT, app.RpT, 2*app.FcT*app.TsTiva,'high');
                elseif app.ClaseT == 3
                    [app.bT, app.aT] = cheby1(app.OrdenT/2, app.RpT, 2*app.FcBandT*app.TsTiva,'bandpass');
                elseif app.ClaseT == 4
                    [app.bT, app.aT] = cheby1(app.OrdenT/2, app.RpT, 2*app.FcBandT*app.TsTiva,'stop');
                end
            elseif app.SubtipoT == 3
                if app.ClaseT == 1
                    [app.bT, app.aT] = cheby2(app.OrdenT, app.RsT, 2*app.FcT*app.TsTiva,'low');
                elseif app.ClaseT == 2
                    [app.bT, app.aT] = cheby2(app.OrdenT, app.RsT, 2*app.FcT*app.TsTiva,'high');
                elseif app.ClaseT == 3
                    [app.bT, app.aT] = cheby2(app.OrdenT/2, app.RsT, 2*app.FcBandT*app.TsTiva,'bandpass');
                elseif app.ClaseT == 4
                    [app.bT, app.aT]  = cheby2(app.OrdenT/2, app.RsT, 2*app.FcBandT*app.TsTiva,'stop');
                end
            end

            % Format the coefficients as strings
            bT_str = num2str(app.bT, '%.6f '); % Adjust the format as needed
            aT_str = num2str(app.aT, '%.6f '); % Adjust the format as needed
            
            % Assign the formatted strings to the Edit Fields
            app.bEditField.Value = bT_str;
            app.aEditField.Value = aT_str;
            
            % Respuesta en frecuencia
            [h, w] = freqz(app.bT, app.aT);
            f_Tiva = w*app.FsTiva/(2*pi);  % vector de frecuencia, en Hz
    
            % Magnitud en dB
            mag_dB = 20 * log10(abs(h));
    
            if app.MagFRTiva == 1
                if app.FreFRTiva == 1
                    % Limpiar el gráfico antes de dibujar
                    cla(app.RFGrafica_2);
            
                    plot(app.RFGrafica_2, w, mag_dB, 'Color', '#752642');
                    xlim(app.RFGrafica_2, [w(1), w(end)]);
                    xlabel(app.RFGrafica_2, '\omega (rad/muestra)');
                    ylabel(app.RFGrafica_2, 'Magnitud en dB');
                    grid(app.RFGrafica_2, 'on');

                    % Limpiar el gráfico antes de dibujar
                    cla(app.RFGrafica_4);
            
                    plot(app.RFGrafica_4, w, angle(h), 'Color', '#752642');
                    xlim(app.RFGrafica_4, [w(1), w(end)]);
                    xlabel(app.RFGrafica_4, '\omega (rad/muestra)');
                    ylabel(app.RFGrafica_4, 'Fase (rad)');
                    grid(app.RFGrafica_4, 'on');
                elseif app.FreFRTiva == 2
                    % Limpiar el gráfico antes de dibujar
                    cla(app.RFGrafica_2);

                    plot(app.RFGrafica_2, f_Tiva, mag_dB, 'Color', '#752642');
                    xlim(app.RFGrafica_2, [f_Tiva(1), f_Tiva(end)]);
                    xlabel(app.RFGrafica_2, 'f (Hz)');
                    ylabel(app.RFGrafica_2, 'Magnitud en dB');
                    grid(app.RFGrafica_2, 'on');

                    % Limpiar el gráfico antes de dibujar
                    cla(app.RFGrafica_4);
            
                    plot(app.RFGrafica_4, f_Tiva, angle(h), 'Color', '#752642');
                    xlim(app.RFGrafica_4, [f_Tiva(1), f_Tiva(end)]);
                    xlabel(app.RFGrafica_4, '\omega (rad/muestra)');
                    ylabel(app.RFGrafica_4, 'Fase (rad)');
                    grid(app.RFGrafica_4, 'on');
                end
            elseif app.MagFRTiva == 2
                if app.FreFRTiva == 1
                    % Limpiar el gráfico antes de dibujar
                    cla(app.RFGrafica_2);
           
                    plot(app.RFGrafica_2, w, abs(h), 'Color', '#752642');
                    xlim(app.RFGrafica_2, [w(1), w(end)]);
                    xlabel(app.RFGrafica_2, '\omega (rad/muestra)');
                    ylabel(app.RFGrafica_2, 'Magnitud');
                    grid(app.RFGrafica_2, 'on');

                    % Limpiar el gráfico antes de dibujar
                    cla(app.RFGrafica_4);
            
                    plot(app.RFGrafica_4, w, angle(h), 'Color', '#752642');
                    xlim(app.RFGrafica_4, [w(1), w(end)]);
                    xlabel(app.RFGrafica_4, '\omega (rad/muestra)');
                    ylabel(app.RFGrafica_4, 'Fase (rad)');
                    grid(app.RFGrafica_4, 'on');
                elseif app.FreFRTiva == 2
                    % Limpiar el gráfico antes de dibujar
                    cla(app.RFGrafica_2);
    
                    plot(app.RFGrafica_2, f_Tiva, abs(h), 'Color', '#752642');
                    xlim(app.RFGrafica_2, [f_Tiva(1), f_Tiva(end)]);
                    xlabel(app.RFGrafica_2, 'f (Hz)');
                    ylabel(app.RFGrafica_2, 'Magnitud');
                    grid(app.RFGrafica_2, 'on');

                    % Limpiar el gráfico antes de dibujar
                    cla(app.RFGrafica_4);
            
                    plot(app.RFGrafica_4, f_Tiva, angle(h), 'Color', '#752642');
                    xlim(app.RFGrafica_4, [f_Tiva(1), f_Tiva(end)]);
                    xlabel(app.RFGrafica_4, 'f (Hz)');
                    ylabel(app.RFGrafica_4, 'Fase (rad)');
                    grid(app.RFGrafica_4, 'on');
                 end
            end  
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TiempoEditField.Enable = "off";
            app.CAPTURARButton.Enable = "off";
            app.VentanaDropDown.Enable = "off";
            app.SubtipoDropDown.Enable = "off";
            app.FrecuenciadeCorteAltaEditField.Enable = "off";
            app.FrecuenciadeCorteBajaEditField.Enable = "off";
            app.PassbandRippleRpEditField.Enable = "off";
            app.StopbandAttenuationRsEditField.Enable = "off";
            app.FrecuenciadeCorteAltaEditField_2.Enable = "off";
            app.FrecuenciadeCorteBajaEditField_2.Enable = "off";
            app.PassbandRippleRpEditField_2.Enable = "off";
            app.StopbandAttenuationRsEditField_2.Enable = "off";
            app.ENVIARATIVACButton.Enable = "off";
            app.bEditField.Enable = "off";
            app.aEditField.Enable = "off";
            app.OrdenSpinner.Value = 2;
            generarFiltro(app)
            FiltroTiva(app)
        end

        % Value changed function: TipoDropDown
        function TipoDropDownValueChanged(app, event)
            % Asignar valores numéricos según la clase seleccionada
            switch app.TipoDropDown.Value
                case 'FIR'
                    app.TipoInterfaz = 1;
                case 'IIR'
                    app.TipoInterfaz = 2;
                otherwise
                    app.TipoInterfaz = NaN; % Valor por defecto si no hay coincidencia
            end 
            generarFiltro(app)
        end

        % Value changed function: SubtipoDropDown
        function SubtipoDropDownValueChanged(app, event)
            % Asignar valores numéricos según el subtipo seleccionado
            switch app.SubtipoDropDown.Value
                case 'Butterworth'
                    app.SubtipoI = 1;
                case 'Chebyshev I'
                    app.SubtipoI = 2;
                case 'Chebyshev II'
                    app.SubtipoI = 3;
                otherwise
                    app.SubtipoI = NaN; % Valor por defecto si no hay coincidencia
            end

            % Ajustar habilitación de los campos de frecuencia de corte
            if app.SubtipoI == 1
                app.PassbandRippleRpEditField.Enable = 'off';
                app.StopbandAttenuationRsEditField.Enable = 'off';
            elseif app.SubtipoI == 2 
                app.PassbandRippleRpEditField.Enable = 'on';
                app.StopbandAttenuationRsEditField.Enable = 'off';
            elseif app.SubtipoI == 3
                app.PassbandRippleRpEditField.Enable = 'off';
                app.StopbandAttenuationRsEditField.Enable = 'on';
            else
                app.PassbandRippleRpEditField.Enable = 'off';
                app.StopbandAttenuationRsEditField.Enable = 'off';
            end

            generarFiltro(app)
        end

        % Value changed function: ClaseDropDown
        function ClaseDropDownValueChanged(app, event)
            % Asignar valores numéricos según la clase seleccionada
            switch app.ClaseDropDown.Value
                case 'Pasabajas'
                    app.ClaseI = 1;
                case 'Pasalatas'
                    app.ClaseI = 2;
                case 'Pasabandas'
                    app.ClaseI = 3;
                case 'Eliminabandas'
                    app.ClaseI = 4;
                otherwise
                    app.ClaseI = NaN; % Valor por defecto si no hay coincidencia
            end
 
            % Ajustar habilitación de los campos de frecuencia de corte
            if app.ClaseI == 1 || app.ClaseI == 2
                % Pasabajas o Pasabandas
                app.FrecuenciadeCorteEditField.Enable = 'on';
                app.FrecuenciadeCorteBajaEditField.Enable = 'off';
                app.FrecuenciadeCorteAltaEditField.Enable = 'off';
                app.OrdenSpinner.Step = 1; % Paso a 1
            elseif app.ClaseI == 3 || app.ClaseI == 4
                % Eliminabandas o Pasaaltas
                app.FrecuenciadeCorteEditField.Enable = 'off';
                app.FrecuenciadeCorteBajaEditField.Enable = 'on';
                app.FrecuenciadeCorteAltaEditField.Enable = 'on';
                app.OrdenI = app.OrdenSpinner.Value; 
                app.previousOrdenI = app.OrdenSpinner.Value;
                app.OrdenSpinner.Step = 2; % Paso a 2
                if mod(app.OrdenSpinner.Value, 2) ~= 0
                    uialert(app.UIFigure, 'El Orden debe ser un número par', 'Error');
                    app.OrdenI = app.OrdenSpinner.Value + 1; % Asegurar que el valor sea par
                    app.OrdenSpinner.Value = app.OrdenSpinner.Value + 1;
                else
                    app.OrdenI = app.OrdenSpinner.Value;
                end
            else
                % Otros casos
                app.FrecuenciadeCorteEditField.Enable = 'off';
                app.FrecuenciadeCorteBajaEditField.Enable = 'off';
                app.FrecuenciadeCorteAltaEditField.Enable = 'off';
            end
            generarFiltro(app)
        end

        % Value changed function: OrdenSpinner
        function OrdenSpinnerValueChanged(app, event)
            % Ajustar habilitación de los campos de frecuencia de corte
            app.OrdenI = app.OrdenSpinner.Value; 
            app.previousOrdenI = app.OrdenSpinner.Value;
            if (app.ClaseI == 3 || app.ClaseI == 4) && mod(app.OrdenSpinner.Value, 2) ~= 0
                app.OrdenSpinner.Step = 2; % Paso a 2
                uialert(app.UIFigure, 'El Orden debe ser un número par', 'Error');
                app.OrdenI = app.OrdenSpinner.Value + 1; % Asegurar que el valor sea par
                app.OrdenSpinner.Value = app.OrdenSpinner.Value + 1;
            elseif (app.TipoInterfaz == 1) && mod(app.OrdenSpinner.Value, 2) ~= 0
                uialert(app.UIFigure, 'El Orden debe ser un número par', 'Error');
                app.OrdenI = app.OrdenSpinner.Value + 1; % Asegurar que el valor sea par
                app.OrdenSpinner.Value = app.OrdenSpinner.Value + 1;  
            else
                app.OrdenI = app.OrdenSpinner.Value;
            end
            generarFiltro(app) 
        end

        % Value changed function: FrecuenciadeCorteEditField
        function FrecuenciadeCorteEditFieldValueChanged(app, event)
            app.FcI = app.FrecuenciadeCorteEditField.Value;
            generarFiltro(app)
        end

        % Value changed function: FrecuenciadeCorteBajaEditField
        function FrecuenciadeCorteBajaEditFieldValueChanged(app, event)
            app.FcLI = app.FrecuenciadeCorteBajaEditField.Value;
            if app.FcLI > app.FrecuenciadeCorteAltaEditField.Value
                uialert(app.UIFigure, 'La frecuencia de corte baja debe ser menor que la frecuencia de corte alta', 'Error');
                % Restablecer al valor anterior
                app.FcLI = app.FrecuenciadeCorteAltaEditField.Value - 1;
                app.FrecuenciadeCorteBajaEditField.Value = app.FrecuenciadeCorteAltaEditField.Value - 1;
            else
                app.FcLI = app.FcLI;
            end
            app.FcBandI = [app.FcLI app.FcHI];
            generarFiltro(app)
        end

        % Value changed function: FrecuenciadeCorteAltaEditField
        function FrecuenciadeCorteAltaEditFieldValueChanged(app, event)
            app.FcHI = app.FrecuenciadeCorteAltaEditField.Value;
            if app.FcHI < app.FrecuenciadeCorteBajaEditField.Value
                uialert(app.UIFigure, 'La frecuencia de corte baja debe ser menor que la frecuencia de corte alta', 'Error');
                % Restablecer al valor anterior
                app.FcHI = app.FrecuenciadeCorteBajaEditField.Value + 1;
                app.FrecuenciadeCorteAltaEditField.Value = app.FrecuenciadeCorteBajaEditField.Value + 1;
            else
                app.FcLI = app.FcLI;
            end
            app.FcBandI = [app.FcLI app.FcHI];
            generarFiltro(app)
            
            app.FcHI = app.FrecuenciadeCorteAltaEditField.Value;
            generarFiltro(app)
        end

        % Value changed function: StopbandAttenuationRsEditField
        function StopbandAttenuationRsEditFieldValueChanged(app, event)
            app.RsI = app.StopbandAttenuationRsEditField.Value;
            generarFiltro(app)
        end

        % Value changed function: PassbandRippleRpEditField
        function PassbandRippleRpEditFieldValueChanged(app, event)
            app.RpI = app.PassbandRippleRpEditField.Value;
            generarFiltro(app)
        end

        % Selection changed function: MagnitudButtonGroup
        function MagnitudButtonGroupSelectionChanged(app, event)
            % Asignar valores numéricos según la magnitud seleccionada
            selectedButton = app.MagnitudButtonGroup.SelectedObject;
            if selectedButton == app.EndBButton
                app.MagFR = 1;
            elseif selectedButton == app.NoendBButton
                app.MagFR = 2;
            else
                app.MagFR = NaN; % Valor por defecto si no hay coincidencia
            end
            generarFiltro(app)
        end

        % Selection changed function: FrecuenciaButtonGroup
        function FrecuenciaButtonGroupSelectionChanged(app, event)
            % Asignar valores numéricos según la frecuencia seleccionada
            selectedButton = app.FrecuenciaButtonGroup.SelectedObject;
            if selectedButton == app.radmuestraButton
                app.FreFR = 1;
            elseif selectedButton == app.HzButton
                app.FreFR = 2;
            else
                app.FreFR = NaN; % Valor por defecto si no hay coincidencia
            end
            generarFiltro(app)
        end

        % Value changed function: VentanaDropDown
        function VentanaDropDownValueChanged(app, event)
            % Asignar valores numéricos según la clase seleccionada
            switch app.VentanaDropDown.Value
                case 'Rectangular'
                    app.Ventana = 1;
                case 'Kaiser'
                    app.Ventana = 2;
                case 'Blackman'
                    app.Ventana = 3;
                case 'Hamming'
                    app.Ventana = 4;
                 case 'Hann'
                     app.Ventana = 5;
                 case 'Bartlett'
                     app.Ventana = 6;
                otherwise
                    app.Ventana = NaN;
            end
            generarFiltro(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            switch app.Switch.Value
                case 'Archivo'
                    delete(app.s);
                    app.CONECTARATIVACButton.Enable = "on";
                    app.Lamp_2.Color = '#411020';
                    drawnow;
                    app.SELECCIONARButton.Enable = "on";
                    app.TiempoEditField.Enable = "off";
                    app.CAPTURARButton.Enable = "off";
                    app.ENVIARATIVACButton.Enable = "off";
        
                case 'Tiva C'
                    app.Fs = 1000;
                    app.Ts = 1/1000;

                    % Actualizar los límites de los campos de edición
                    app.FrecuenciadeCorteEditField.Limits = [1, (app.Fs / 2) - 1];
                    app.FrecuenciadeCorteAltaEditField.Limits = [1, (app.Fs / 2) - 1];
                    app.FrecuenciadeCorteBajaEditField.Limits = [1, (app.Fs / 2) - 1];
                    app.FcHI = (app.Fs / 2) - 10;
                    app.FcLI = (app.Fs / 2) - 20;
                    app.FcI = 1;
                    app.FcBandI = [app.FcLI app.FcHI]; 
                    app.FrecuenciadeCorteEditField.Value = 1;
                    app.FrecuenciadeCorteAltaEditField.Value = (app.Fs / 2) - 10;
                    app.FrecuenciadeCorteBajaEditField.Value = (app.Fs / 2) - 20;
        
                    generarFiltro(app);
                    try
                        % Intentar establecer conexión con el puerto serie
                        app.s = serialport("COM9", 230400);
                        flush(app.s, "input");
                        
                        % Mostrar mensaje de éxito si la conexión se establece correctamente
                        uialert(app.UIFigure, 'Se ha conectado exitosamente a Tiva C.', 'Éxito', 'Icon', 'success');
                        
                        % Habilitar los controles correspondientes
                        app.SELECCIONARButton.Enable = "off";
                        app.TiempoEditField.Enable = "on";
                        app.CAPTURARButton.Enable = "on";
                        app.Lamp_2.Color = '#d75c77';
                        drawnow;
                        app.CONECTARATIVACButton.Enable = "off";
                        app.ENVIARATIVACButton.Enable = "on";
        
                    catch ME
                        % Mostrar mensaje de error si falla la conexión
                        uialert(app.UIFigure, sprintf('Error al conectar a Tiva C: %s', ME.message), 'Error', 'Icon', 'error');
                        
                        % Regresar al estado 'Archivo'
                        app.Switch.Value = 'Archivo';
                        app.SELECCIONARButton.Enable = "on";
                        app.TiempoEditField.Enable = "off";
                        app.CAPTURARButton.Enable = "off";
                        app.ENVIARATIVACButton.Enable = "off";
                    end
        
                otherwise
                    app.SELECCIONARButton.Enable = "off";
                    app.TiempoEditField.Enable = "off";
                    app.CAPTURARButton.Enable = "off";
            end

        end

        % Button pushed function: SELECCIONARButton
        function SELECCIONARButtonPushed(app, event)
            % Abrir la ventana de diálogo para seleccionar el archivo
            [filename, pathname] = uigetfile({'*.mat'}, 'Seleccione un archivo .mat');
            
            if filename == 0
                % El usuario ha cancelado la selección de archivo
                return;
            end
            
            % Cargar las variables desde el archivo seleccionado
            filepath = fullfile(pathname, filename);
            data = load(filepath);
            
            % Obtener las variables x y Fs del archivo
            if isfield(data, 'x') && isfield(data, 'Fs')
            app.x = data.x;
            app.Fs = data.Fs;
            app.Ts = 1/app.Fs; 
        
            % Actualizar los límites de los campos de edición
            app.FrecuenciadeCorteEditField.Limits = [1, (app.Fs / 2) - 1];
            app.FrecuenciadeCorteAltaEditField.Limits = [1, (app.Fs / 2) - 1];
            app.FrecuenciadeCorteBajaEditField.Limits = [1, (app.Fs / 2) - 1];
            app.FcHI = (app.Fs / 2) - 10;
            app.FcLI = (app.Fs / 2) - 20;
            app.FcI = 1;
            app.FcBandI = [app.FcLI app.FcHI]; 
            app.FrecuenciadeCorteEditField.Value = 1;
            app.FrecuenciadeCorteAltaEditField.Value = (app.Fs / 2) - 10;
            app.FrecuenciadeCorteBajaEditField.Value = (app.Fs / 2) - 20;
        
            originalMAt(app);
            generarFiltro(app);
            else
                % El archivo no contiene las variables esperadas
                uialert(app.UIFigure, 'El archivo no contiene las variables x y Fs.', 'Error');
            end
        end

        % Selection changed function: VisualizacinButtonGroup
        function VisualizacinButtonGroupSelectionChanged(app, event)
            % Asignar valores numéricos según la frecuencia seleccionada
            selectedButton = app.VisualizacinButtonGroup.SelectedObject;
            if isempty(app.FIL) || isempty(app.X)
                uialert(app.UIFigure, 'Aún no se tienen datos de uno o ambos espectro para graficar', 'Error');
                app.VisualizacinButtonGroup.SelectedObject = app.PlotButton;
            else
                if selectedButton == app.PlotButton
                app.Vis = 1;
                elseif selectedButton == app.StemButton
                    app.Vis = 2;
                else
                    app.Vis = NaN; % Valor por defecto si no hay coincidencia
                end
                originalMAt(app)
                filtradoMAT(app)
            end
        end

        % Button pushed function: FILTRARButton
        function FILTRARButtonPushed(app, event)
            if isempty(app.bI) || isempty(app.aI)
                uialert(app.UIFigure, 'Aún no se han definido todos los parámetros del filtro', 'Error');
            elseif isempty(app.x)
                uialert(app.UIFigure, 'Aún no se tiene una Señal de Entrada', 'Error');
            else
                filtradoMAT(app)
            end
        end

        % Button pushed function: GUARDARcsvButton
        function GUARDARcsvButtonPushed(app, event)
            if isempty(app.x) || isempty(app.t) || isempty(app.fil)
                uialert(app.UIFigure, 'Aún no se tiene una señal para guardar', 'Error');
            else
                % Solicitar al usuario que seleccione el nombre y la ubicación del archivo
                [file, path] = uiputfile('*.csv', 'Guardar archivo como');
                
                % Verificar si el usuario canceló la operación
                if isequal(file, 0) || isequal(path, 0)
                    uialert(app.UIFigure, 'El guardado del archivo fue cancelado', 'Cancelación');
                else
                    % Generar el nombre completo del archivo
                    fullFileName = fullfile(path, file);
                    
                    % Crear datos para guardar
                    dataToSave = table(app.t(:), app.x(:), app.fil(:), 'VariableNames', {'t', 'x', 'filtrada'});
                    
                    % Guardar los datos en un archivo CSV
                    writetable(dataToSave, fullFileName);
                    
                    % Notificar al usuario que el archivo se guardó correctamente
                    uialert(app.UIFigure, 'El archivo se guardó correctamente', 'Éxito', 'Icon', 'success');
                end
            end

           
        end

        % Button pushed function: GUARDARmatButton
        function GUARDARmatButtonPushed(app, event)
            if isempty(app.bI) || isempty(app.aI)
                uialert(app.UIFigure, 'Aún no se han definido todos los parámetros del filtro', 'Error');
            else
                % Solicitar al usuario que seleccione el nombre y la ubicación del archivo
                [file, path] = uiputfile('*.mat', 'Guardar archivo como');
                
                % Verificar si el usuario canceló la operación
                if isequal(file, 0) || isequal(path, 0)
                    uialert(app.UIFigure, 'El guardado del archivo fue cancelado', 'Cancelación');
                else
                    % Generar el nombre completo del archivo
                    fullFileName = fullfile(path, file);
                    
                    % Definir las variables a guardar
                    Fs = app.Fs;
                    if app.TipoInterfaz == 1
                        Tipo = 'FIR';
                    elseif app.TipoInterfaz == 2
                        Tipo = 'IIR';
                    else
                        Tipo = Nan;
                    end

                    if app.SubtipoI == 1
                        Subtipo = 'Butterworth';
                    elseif app.SubtipoI == 2
                        Subtipo = 'Chebyshev I';
                    elseif app.SubtipoI == 3
                        Subtipo = 'Chebyshev II';
                    else
                        Subtipo = Nan;
                    end

                    if app.ClaseI == 1
                        Clase = 'Pasabajas';
                    elseif app.ClaseI == 2
                        Clase = 'Pasaaltas';
                    elseif app.ClaseI == 3
                        Clase = 'Pasabandas';
                    elseif app.ClaseI == 4
                        Clase = 'Eliminabandas';
                    else
                        Clase = Nan;
                    end

                    if app.Ventana == 1
                        Ventana = 'Rectangular';
                    elseif app.Ventana == 2
                        Ventana = 'Barlett';
                    elseif app.Ventana == 3
                        Ventana = 'Hann';
                    elseif app.Ventana == 4
                        Ventana = 'Hamming';
                    elseif app.Ventana == 5
                        Ventana = 'Blackman';
                    elseif app.Ventana == 6
                        Ventana = 'Kaiser';
                    else
                        Ventana = Nan;
                    end

                    Orden = app.OrdenI;
                    Fc = app.FcI;
                    Fc_Banda = app.FcBandI;
                    Rs = app.RsI;
                    Rp = app.RpI;

                    % Guardar las variables en un archivo .mat
                    save(fullFileName, 'Fs', 'Tipo', 'Subtipo', 'Clase', 'Ventana', 'Orden', 'Fc', 'Fc_Banda', 'Rs', 'Rp');
                    
                    % Notificar al usuario que el archivo se guardó correctamente
                    uialert(app.UIFigure, 'El archivo se guardó correctamente', 'Éxito', 'Icon', 'success');
                end
               
            end
        end

        % Button pushed function: CAPTURARButton
        function CAPTURARButtonPushed(app, event)
            % Configurar los parámetros
            app.Fs = 1000;
            app.Ts = 1 / app.Fs;
            app.FrecuenciadeCorteEditField.Limits = [1, 499];
            app.FrecuenciadeCorteAltaEditField.Limits = [1, 499];
            app.FrecuenciadeCorteBajaEditField.Limits = [1, 499];

            % Intentar establecer conexión con el puerto serie
            try
                flush(app.s, "input");
                muestreo = 1000*app.TiempoEditField.Value;
                data = zeros(1, muestreo);
        
                % Abrir la conexión
                fopen(app.s);
    
                % Abrir la conexión
                pause(1);
    
                % Indicar que la transferencia de datos está en curso
                app.Lamp.Color = '#d75c77'; % Encender la lámpara
                drawnow; % Actualizar la interfaz gráfica
        
                % Iniciar la lectura de datos
                for i = 1:muestreo
                    data(i) = fscanf(app.s, '%d');
                end
        
                % Cerrar la conexión
                fclose(app.s);
    
                % Apagar la lámpara después de la transferencia de datos
                app.Lamp.Color = '#411020'; % Apagar la lámpara
                drawnow; % Actualizar la interfaz gráfica
    
                % Escalar y plotear los datos
                data = (data / 4095) * 3.3;
                app.x = data;
                originalMAt(app)
             catch ME
                % Mostrar mensaje de error si la conexión falla
                uialert(app.UIFigure, 'Se perdió la Conexión con Tiva C', 'Error');
                % Apagar la lámpara en caso de error
                app.Lamp.Color = '#411020';
                drawnow; % Actualizar la interfaz gráfica

                % Regresar al estado 'Archivo'
                app.Switch.Value = 'Archivo';
                app.SELECCIONARButton.Enable = "on";
                app.TiempoEditField.Enable = "off";
                app.CAPTURARButton.Enable = "off";
                app.Lamp_2.Color = '#411020';
                drawnow;
                app.CONECTARATIVACButton.Enable = "on";
                app.ENVIARATIVACButton.Enable = "off";
                return;
            end
        end

        % Value changed function: SubtipoDropDown_2
        function SubtipoDropDown_2ValueChanged(app, event)
            % Asignar valores numéricos según el subtipo seleccionado
            switch app.SubtipoDropDown_2.Value
                case 'Butterworth'
                    app.SubtipoT = 1;
                case 'Chebyshev I'
                    app.SubtipoT = 2;
                case 'Chebyshev II'
                    app.SubtipoT = 3;
                otherwise
                    app.SubtipoT = NaN; % Valor por defecto si no hay coincidencia
            end

            % Ajustar habilitación de los campos de frecuencia de corte
            if app.SubtipoT == 1
                app.PassbandRippleRpEditField_2.Enable = 'off';
                app.StopbandAttenuationRsEditField_2.Enable = 'off';
            elseif app.SubtipoT == 2 
                app.PassbandRippleRpEditField_2.Enable = 'on';
                app.StopbandAttenuationRsEditField_2.Enable = 'off';
            elseif app.SubtipoT == 3
                app.PassbandRippleRpEditField_2.Enable = 'off';
                app.StopbandAttenuationRsEditField_2.Enable = 'on';
            else
                app.PassbandRippleRpEditField_2.Enable = 'off';
                app.StopbandAttenuationRsEditField_2.Enable = 'off';
            end

            FiltroTiva(app)
            
        end

        % Value changed function: ClaseDropDown_2
        function ClaseDropDown_2ValueChanged(app, event)
            % Asignar valores numéricos según la clase seleccionada
            switch app.ClaseDropDown_2.Value
                case 'Pasabajas'
                    app.ClaseT = 1;
                case 'Pasalatas'
                    app.ClaseT = 2;
                case 'Pasabandas'
                    app.ClaseT = 3;
                case 'Eliminabandas'
                    app.ClaseT = 4;
                otherwise
                    app.ClaseT = NaN; % Valor por defecto si no hay coincidencia
            end
 
            % Ajustar habilitación de los campos de frecuencia de corte
            if app.ClaseT == 1 || app.ClaseT == 2
                % Pasabajas o Pasabandas
                app.FrecuenciadeCorteEditField_2.Enable = 'on';
                app.FrecuenciadeCorteBajaEditField_2.Enable = 'off';
                app.FrecuenciadeCorteAltaEditField_2.Enable = 'off';
                app.Button.Enable = 'on';
                app.Button_3.Enable = 'on';
            elseif app.ClaseT == 3 || app.ClaseT == 4
                % Eliminabandas o Pasaaltas
                app.FrecuenciadeCorteEditField_2.Enable = 'off';
                app.FrecuenciadeCorteBajaEditField_2.Enable = 'on';
                app.FrecuenciadeCorteAltaEditField_2.Enable = 'on';
                app.Button.Enable = 'off';
                app.Button_3.Enable = 'off';
                if mod(app.OrdenT, 2) ~= 0
                    uialert(app.UIFigure, 'El Orden debe ser un número par', 'Error');
                    app.OrdenButtonGroup.SelectedObject = app.Button_2;
                end
            else
                % Otros casos
                app.FrecuenciadeCorteEditField.Enable = 'off';
                app.FrecuenciadeCorteBajaEditField.Enable = 'off';
                app.FrecuenciadeCorteAltaEditField.Enable = 'off';
            end
            FiltroTiva(app)
        end

        % Selection changed function: OrdenButtonGroup
        function OrdenButtonGroupSelectionChanged(app, event)
            % Asignar valores numéricos según la frecuencia seleccionada
            selectedButton = app.OrdenButtonGroup.SelectedObject;
            if selectedButton == app.Button
                app.OrdenT = 1;
            elseif selectedButton == app.Button_2
                app.OrdenT = 2;
            elseif selectedButton == app.Button_3
                app.OrdenT = 3;
            elseif selectedButton == app.Button_4
                app.OrdenT = 4;
            else
                app.OrdenT = NaN; % Valor por defecto si no hay coincidencia
            end
            FiltroTiva(app)
        end

        % Value changed function: FrecuenciadeCorteEditField_2
        function FrecuenciadeCorteEditField_2ValueChanged(app, event)
            app.FcT = app.FrecuenciadeCorteEditField_2.Value;
            FiltroTiva(app)
            
        end

        % Value changed function: FrecuenciadeCorteBajaEditField_2
        function FrecuenciadeCorteBajaEditField_2ValueChanged(app, event)
            app.FcLT = app.FrecuenciadeCorteBajaEditField_2.Value;
            if app.FcLT > app.FrecuenciadeCorteAltaEditField_2.Value
                uialert(app.UIFigure, 'La frecuencia de corte baja debe ser menor que la frecuencia de corte alta', 'Error');
                % Restablecer al valor anterior
                app.FcLT = app.FrecuenciadeCorteAltaEditField_2.Value - 1;
                app.FrecuenciadeCorteBajaEditField_2.Value = app.FrecuenciadeCorteAltaEditField_2.Value - 1;
            else
                app.FcLT = app.FcLT;
            end
            app.FcBandT = [app.FcLT app.FcHT];
            FiltroTiva(app)
        end

        % Value changed function: FrecuenciadeCorteAltaEditField_2
        function FrecuenciadeCorteAltaEditField_2ValueChanged(app, event)
            app.FcHT = app.FrecuenciadeCorteAltaEditField_2.Value;
            if app.FcHT < app.FrecuenciadeCorteBajaEditField_2.Value
                uialert(app.UIFigure, 'La frecuencia de corte baja debe ser menor que la frecuencia de corte alta', 'Error');
                % Restablecer al valor anterior
                app.FcHT = app.FrecuenciadeCorteBajaEditField_2.Value + 1;
                app.FrecuenciadeCorteAltaEditField_2.Value = app.FrecuenciadeCorteBajaEditField_2.Value + 1;
            else
                app.FcLT = app.FcLT;
            end
            app.FcBandT = [app.FcLT app.FcHT];
            FiltroTiva(app)
        end

        % Value changed function: StopbandAttenuationRsEditField_2
        function StopbandAttenuationRsEditField_2ValueChanged(app, event)
            app.RsT = app.StopbandAttenuationRsEditField_2.Value;
            FiltroTiva(app)  
        end

        % Value changed function: PassbandRippleRpEditField_2
        function PassbandRippleRpEditField_2ValueChanged(app, event)
            app.RpT = app.PassbandRippleRpEditField_2.Value;
            FiltroTiva(app)         
        end

        % Selection changed function: MagnitudButtonGroup_2
        function MagnitudButtonGroup_2SelectionChanged(app, event)
            % Asignar valores numéricos según la magnitud seleccionada
            selectedButton = app.MagnitudButtonGroup_2.SelectedObject;
            if selectedButton == app.EndBButton_2
                app.MagFRTiva = 1;
            elseif selectedButton == app.NoendBButton_2
                app.MagFRTiva = 2;
            else
                app.MagFRTiva = NaN; % Valor por defecto si no hay coincidencia
            end
            FiltroTiva(app)  
        end

        % Selection changed function: FrecuenciaButtonGroup_2
        function FrecuenciaButtonGroup_2SelectionChanged(app, event)
            % Asignar valores numéricos según la frecuencia seleccionada
            selectedButton = app.FrecuenciaButtonGroup_2.SelectedObject;
            if selectedButton == app.radmuestraButton_2
                app.FreFRTiva = 1;
            elseif selectedButton == app.HzButton_2
                app.FreFRTiva = 2;
            else
                app.FreFRTiva = NaN; % Valor por defecto si no hay coincidencia
            end
            FiltroTiva(app)  
        end

        % Button pushed function: CONECTARATIVACButton
        function CONECTARATIVACButtonPushed(app, event)
            try
                % Intentar establecer conexión con el puerto serie
                app.s = serialport("COM9", 230400);
                flush(app.s, "input");
                
                % Mostrar mensaje de éxito si la conexión se establece correctamente
                uialert(app.UIFigure, 'Se ha conectado exitosamente a Tiva C.', 'Éxito', 'Icon', 'success');
                
                % Habilitar los controles correspondientes
                app.Switch.Value = 'Tiva C';    
                app.SELECCIONARButton.Enable = "off";
                app.TiempoEditField.Enable = "on";
                app.CAPTURARButton.Enable = "on";
                app.Lamp_2.Color = '#d75c77';
                drawnow;
                app.CONECTARATIVACButton.Enable = "off";
                app.ENVIARATIVACButton.Enable = "on";

            catch ME
                % Mostrar mensaje de error si falla la conexión
                uialert(app.UIFigure, sprintf('Error al conectar a Tiva C: %s', ME.message), 'Error', 'Icon', 'error');
                
                % Regresar al estado 'Archivo'
                app.Switch.Value = 'Archivo';
                app.SELECCIONARButton.Enable = "on";
                app.TiempoEditField.Enable = "off";
                app.CAPTURARButton.Enable = "off";
                app.Lamp_2.Color = '#411020';
                drawnow;
                app.CONECTARATIVACButton.Enable = "on";
            end

        end

        % Button pushed function: GUARDARmatButton_2
        function GUARDARmatButton_2Pushed(app, event)
            if isempty(app.bT) || isempty(app.aT)
                uialert(app.UIFigure, 'Aún no se han definido todos los parámetros del filtro', 'Error');
            else
                % Solicitar al usuario que seleccione el nombre y la ubicación del archivo
                [file, path] = uiputfile('*.mat', 'Guardar archivo como');
                
                % Verificar si el usuario canceló la operación
                if isequal(file, 0) || isequal(path, 0)
                    uialert(app.UIFigure, 'El guardado del archivo fue cancelado', 'Cancelación');
                else
                    % Generar el nombre completo del archivo
                    fullFileName = fullfile(path, file);

                    % Definir las variables a guardar
                    Fs = app.FsTiva;
                    Tipo = 'IIR';

                    if app.SubtipoT == 1
                        Subtipo = 'Butterworth';
                    elseif app.SubtipoT == 2
                        Subtipo = 'Chebyshev I';
                    elseif app.SubtipoT == 3
                        Subtipo = 'Chebyshev II';
                    else
                        Subtipo = Nan;
                    end

                    if app.ClaseT == 1
                        Clase = 'Pasabajas';
                    elseif app.ClaseT == 2
                        Clase = 'Pasaaltas';
                    elseif app.ClaseT == 3
                        Clase = 'Pasabandas';
                    elseif app.ClaseT == 4
                        Clase = 'Eliminabandas';
                    else
                        Clase = Nan;
                    end

                    Orden = app.OrdenT;
                    Fc = app.FcT;
                    Fc_Banda = app.FcBandT;
                    Rs = app.RsT;
                    Rp = app.RpT;

                    % Guardar las variables en un archivo .mat
                    save(fullFileName, 'Fs', 'Tipo', 'Subtipo', 'Clase', 'Orden', 'Fc', 'Fc_Banda', 'Rs', 'Rp');

                    % Notificar al usuario que el archivo se guardó correctamente
                    uialert(app.UIFigure, 'El archivo se guardó correctamente', 'Éxito', 'Icon', 'success');
                end
               
            end
        end

        % Button pushed function: ENVIARATIVACButton
        function ENVIARATIVACButtonPushed(app, event)
            % Intentar establecer conexión con el puerto serie
            try
                try
                    % Asegurarse de que ambos vectores tengan al menos 5 elementos
                    if length(app.aT) < 5
                        app.aT = [app.aT, zeros(1, 5 - length(app.aT))];
                    end
                    if length(app.bT) < 5
                        app.bT = [app.bT, zeros(1, 5 - length(app.bT))];
                    end
                    
                    % Combina los vectores asegurando un total de 10 elementos
                    app.coeficientes = [app.bT(1:5), app.aT(2:5)];
                    
                    % Recorre cada elemento de coeficientes y aplica las condiciones
                    for n = 1:length(app.coeficientes)
                        if (app.coeficientes(n) >= 100000 || app.coeficientes(n) <= -10000)
                            dato = sprintf('%.1f', app.coeficientes(n));
                        elseif (app.coeficientes(n) >= 10000 || app.coeficientes(n) <= -1000)
                            dato = sprintf('%.2f', app.coeficientes(n));
                        elseif (app.coeficientes(n) >= 1000 || app.coeficientes(n) <= -100)
                            dato = sprintf('%.3f', app.coeficientes(n));
                        elseif (app.coeficientes(n) >= 100 || app.coeficientes(n) <= -10)
                            dato = sprintf('%.4f', app.coeficientes(n));
                        elseif (app.coeficientes(n) >= 10 || app.coeficientes(n) < 0)
                            dato = sprintf('%.5f', app.coeficientes(n));
                        else
                            dato = sprintf('%.6f', app.coeficientes(n));
                        end

                        % Mandar al Serial
                        writeline(app.s, dato);
                        pause(0.1);
                    end
                    
                    % Mostrar mensaje de éxito
                    uialert(app.UIFigure, 'Coeficientes enviados exitosamente a Tiva C.', 'Éxito', 'Icon', 'success');
                catch ME
                    % Mostrar mensaje de error si algo falla
                    uialert(app.UIFigure, sprintf('Error al enviar coeficientes: %s', ME.message), 'Error', 'Icon', 'error');
                    % Apagar la lámpara en caso de error
                    app.Lamp.Color = '#411020';
                    drawnow; % Actualizar la interfaz gráfica
        
                    % Regresar al estado 'Archivo'
                    app.Switch.Value = 'Archivo';
                    app.SELECCIONARButton.Enable = "on";
                    app.TiempoEditField.Enable = "off";
                    app.CAPTURARButton.Enable = "off";
                    app.Lamp_2.Color = '#411020';
                    drawnow;
                    app.CONECTARATIVACButton.Enable = "on";
                    app.ENVIARATIVACButton.Enable = "off";
                    return;
                end
            catch ME
                % Mostrar mensaje de error si la conexión falla
                uialert(app.UIFigure, 'Se perdió la Conexión con Tiva C', 'Error');
                % Apagar la lámpara en caso de error
                app.Lamp.Color = '#411020';
                drawnow; % Actualizar la interfaz gráfica
    
                % Regresar al estado 'Archivo'
                app.Switch.Value = 'Archivo';
                app.SELECCIONARButton.Enable = "on";
                app.TiempoEditField.Enable = "off";
                app.CAPTURARButton.Enable = "off";
                app.Lamp_2.Color = '#411020';
                drawnow;
                app.CONECTARATIVACButton.Enable = "on";
                app.ENVIARATIVACButton.Enable = "off";
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.INTERFAZ = uitab(app.TabGroup);
            app.INTERFAZ.Title = 'Interfaz';
            app.DISEODELFILTROPanel = uipanel(app.INTERFAZ);
            app.DISEODELFILTROPanel.Title = 'DISEÑO DEL FILTRO';
            app.OrdenSpinnerLabel = uilabel(app.DISEODELFILTROPanel);
            app.OrdenSpinnerLabel.Text = 'Orden';
            app.OrdenSpinner = uispinner(app.DISEODELFILTROPanel);
            app.OrdenSpinner.Limits = [1 499];
            app.OrdenSpinner.RoundFractionalValues = 'on';
            app.OrdenSpinner.ValueChangedFcn = createCallbackFcn(app, @OrdenSpinnerValueChanged, true);
            app.OrdenSpinner.Value = 1;
            app.TipoDropDownLabel = uilabel(app.DISEODELFILTROPanel);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.TipoDropDown = uidropdown(app.DISEODELFILTROPanel);
            app.TipoDropDown.Items = {'FIR', 'IIR'};
            app.TipoDropDown.ValueChangedFcn = createCallbackFcn(app, @TipoDropDownValueChanged, true);
            app.TipoDropDown.Value = 'FIR';
            app.SubtipoDropDownLabel = uilabel(app.DISEODELFILTROPanel);
            app.SubtipoDropDownLabel.Text = 'Subtipo';
            app.SubtipoDropDown = uidropdown(app.DISEODELFILTROPanel);
            app.SubtipoDropDown.Items = {'Butterworth', 'Chebyshev I', 'Chebyshev II'};
            app.SubtipoDropDown.ValueChangedFcn = createCallbackFcn(app, @SubtipoDropDownValueChanged, true);
            app.SubtipoDropDown.Value = 'Butterworth';
            app.ClaseDropDownLabel = uilabel(app.DISEODELFILTROPanel);
            app.ClaseDropDownLabel.Text = 'Clase';
            app.ClaseDropDown = uidropdown(app.DISEODELFILTROPanel);
            app.ClaseDropDown.Items = {'Pasabajas', 'Pasalatas', 'Pasabandas', 'Eliminabandas'};
            app.ClaseDropDown.ValueChangedFcn = createCallbackFcn(app, @ClaseDropDownValueChanged, true);
            app.ClaseDropDown.Value = 'Pasabajas';
            app.FrecuenciadeCorteEditField = uieditfield(app.DISEODELFILTROPanel, 'numeric');
            app.FrecuenciadeCorteEditField.Limits = [1 499];
            app.FrecuenciadeCorteEditField.RoundFractionalValues = 'on';
            app.FrecuenciadeCorteEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadeCorteEditFieldValueChanged, true);
            app.FrecuenciadeCorteEditField.Value = 1;
            app.FrecuenciadeCorteEditFieldLabel = uilabel(app.DISEODELFILTROPanel);
            app.FrecuenciadeCorteEditFieldLabel.Text = 'Frecuencia de Corte';
            app.FrecuenciadeCorteBajaEditFieldLabel = uilabel(app.DISEODELFILTROPanel);
            app.FrecuenciadeCorteBajaEditFieldLabel.Text = 'Frecuencia de Corte Baja';
            app.FrecuenciadeCorteBajaEditField = uieditfield(app.DISEODELFILTROPanel, 'numeric');
            app.FrecuenciadeCorteBajaEditField.Limits = [1 499];
            app.FrecuenciadeCorteBajaEditField.RoundFractionalValues = 'on';
            app.FrecuenciadeCorteBajaEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadeCorteBajaEditFieldValueChanged, true);
            app.FrecuenciadeCorteBajaEditField.Value = 50;
            app.FrecuenciadeCorteAltaEditFieldLabel = uilabel(app.DISEODELFILTROPanel);
            app.FrecuenciadeCorteAltaEditFieldLabel.Text = 'Frecuencia de Corte Alta';
            app.FrecuenciadeCorteAltaEditField = uieditfield(app.DISEODELFILTROPanel, 'numeric');
            app.FrecuenciadeCorteAltaEditField.Limits = [1 499];
            app.FrecuenciadeCorteAltaEditField.RoundFractionalValues = 'on';
            app.FrecuenciadeCorteAltaEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadeCorteAltaEditFieldValueChanged, true);
            app.FrecuenciadeCorteAltaEditField.Value = 100;
            app.StopbandAttenuationRsEditFieldLabel = uilabel(app.DISEODELFILTROPanel);
            app.StopbandAttenuationRsEditFieldLabel.Text = ' Stopband Attenuation (Rs)';
            app.StopbandAttenuationRsEditField = uieditfield(app.DISEODELFILTROPanel, 'numeric');
            app.StopbandAttenuationRsEditField.Limits = [1 5000];
            app.StopbandAttenuationRsEditField.ValueChangedFcn = createCallbackFcn(app, @StopbandAttenuationRsEditFieldValueChanged, true);
            app.StopbandAttenuationRsEditField.Value = 1;
            app.PassbandRippleRpEditFieldLabel = uilabel(app.DISEODELFILTROPanel);
            app.PassbandRippleRpEditFieldLabel.Text = ' Passband Ripple (Rp)';
            app.PassbandRippleRpEditField = uieditfield(app.DISEODELFILTROPanel, 'numeric');
            app.PassbandRippleRpEditField.Limits = [0.1 5000];
            app.PassbandRippleRpEditField.ValueChangedFcn = createCallbackFcn(app, @PassbandRippleRpEditFieldValueChanged, true);
            app.PassbandRippleRpEditField.Value = 0.1;
            app.HzLabel = uilabel(app.DISEODELFILTROPanel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.DISEODELFILTROPanel);
            app.HzLabel_2.Text = 'Hz';
            app.HzLabel_3 = uilabel(app.DISEODELFILTROPanel);
            app.HzLabel_3.Text = 'Hz';
            app.dBLabel = uilabel(app.DISEODELFILTROPanel);
            app.dBLabel.Text = 'dB';
            app.dBLabel_2 = uilabel(app.DISEODELFILTROPanel);
            app.dBLabel_2.Text = 'dB';
            app.VentanaDropDownLabel = uilabel(app.DISEODELFILTROPanel);
            app.VentanaDropDownLabel.Text = 'Ventana';
            app.VentanaDropDown = uidropdown(app.DISEODELFILTROPanel);
            app.VentanaDropDown.Items = {'Rectangular', 'Kaiser', 'Blackman', 'Hamming', 'Hann', 'Bartlett'};
            app.VentanaDropDown.ValueChangedFcn = createCallbackFcn(app, @VentanaDropDownValueChanged, true);
            app.VentanaDropDown.Value = 'Rectangular';
            app.FILTRARButton = uibutton(app.DISEODELFILTROPanel, 'push');
            app.FILTRARButton.ButtonPushedFcn = createCallbackFcn(app, @FILTRARButtonPushed, true);
            app.FILTRARButton.Text = 'FILTRAR';
            app.SEALDEENTRADAPanel = uipanel(app.INTERFAZ);
            app.SEALDEENTRADAPanel.Title = 'SEÑAL DE ENTRADA';
            app.SELECCIONARButton = uibutton(app.SEALDEENTRADAPanel, 'push');
            app.SELECCIONARButton.ButtonPushedFcn = createCallbackFcn(app, @SELECCIONARButtonPushed, true);
            app.SELECCIONARButton.Text = 'SELECCIONAR';
            app.CargararchivomatLabel = uilabel(app.SEALDEENTRADAPanel);
            app.CargararchivomatLabel.Text = 'Cargar archivo .mat ';
            app.CAPTURARButton = uibutton(app.SEALDEENTRADAPanel, 'push');
            app.CAPTURARButton.ButtonPushedFcn = createCallbackFcn(app, @CAPTURARButtonPushed, true);
            app.CAPTURARButton.Text = 'CAPTURAR';
            app.DeTivaCLabel = uilabel(app.SEALDEENTRADAPanel);
            app.DeTivaCLabel.Text = 'De Tiva C';
            app.Label = uilabel(app.SEALDEENTRADAPanel);
            app.Label.Text = '';
            app.Switch = uiswitch(app.SEALDEENTRADAPanel, 'slider');
            app.Switch.Items = {'Archivo', 'Tiva C'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Archivo';
            app.Lamp = uilamp(app.SEALDEENTRADAPanel);
            app.DebetenervariablesFsyxLabel = uilabel(app.SEALDEENTRADAPanel);
            app.DebetenervariablesFsyxLabel.Text = '*Debe tener variables Fs y x';
            app.segLabel = uilabel(app.SEALDEENTRADAPanel);
            app.segLabel.Text = 'seg';
            app.TiempoEditFieldLabel = uilabel(app.SEALDEENTRADAPanel);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.TiempoEditField = uieditfield(app.SEALDEENTRADAPanel, 'numeric');
            app.TiempoEditField.Limits = [0.01 1000];
            app.TiempoEditField.Value = 0.01;
            app.GRFICASPanel = uipanel(app.INTERFAZ);
            app.GRFICASPanel.Title = 'GRÁFICAS';
            app.UIAxes_FiltradaSp = uiaxes(app.GRFICASPanel);
            title(app.UIAxes_FiltradaSp, 'Espectro Señal Procesada')
            xlabel(app.UIAxes_FiltradaSp, 'Frecuencia (Hz)')
            ylabel(app.UIAxes_FiltradaSp, 'Magnitud')
            zlabel(app.UIAxes_FiltradaSp, 'Z')
            app.UIAxes_OriginalSp = uiaxes(app.GRFICASPanel);
            title(app.UIAxes_OriginalSp, 'Espectro Señal Original')
            xlabel(app.UIAxes_OriginalSp, 'Frecuencia (Hz)')
            ylabel(app.UIAxes_OriginalSp, 'Magnitud')
            zlabel(app.UIAxes_OriginalSp, 'Z')
            app.UIAxes_Filtrada = uiaxes(app.GRFICASPanel);
            title(app.UIAxes_Filtrada, 'Señal Procesada')
            xlabel(app.UIAxes_Filtrada, 'Tiempo (s)')
            ylabel(app.UIAxes_Filtrada, 'Magnitud')
            zlabel(app.UIAxes_Filtrada, 'Z')
            app.UIAxes_Original = uiaxes(app.GRFICASPanel);
            title(app.UIAxes_Original, 'Señal Original')
            xlabel(app.UIAxes_Original, 'Tiempo (s)')
            ylabel(app.UIAxes_Original, 'Magnitud')
            zlabel(app.UIAxes_Original, 'Z')
            app.VisualizacinButtonGroup = uibuttongroup(app.GRFICASPanel);
            app.VisualizacinButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VisualizacinButtonGroupSelectionChanged, true);
            app.VisualizacinButtonGroup.Title = 'Visualización';
            app.PlotButton = uiradiobutton(app.VisualizacinButtonGroup);
            app.PlotButton.Text = 'Plot';
            app.PlotButton.Value = true;
            app.StemButton = uiradiobutton(app.VisualizacinButtonGroup);
            app.StemButton.Text = 'Stem';
            app.RESPUESTAENFRECUENCIAPanel = uipanel(app.INTERFAZ);
            app.RESPUESTAENFRECUENCIAPanel.Title = 'RESPUESTA EN FRECUENCIA';
            app.RFGrafica = uiaxes(app.RESPUESTAENFRECUENCIAPanel);
            title(app.RFGrafica, 'Magnitud')
            xlabel(app.RFGrafica, 'rad/muestra')
            ylabel(app.RFGrafica, 'Magnitud en dB')
            zlabel(app.RFGrafica, 'Z')
            app.RFGrafica_3 = uiaxes(app.RESPUESTAENFRECUENCIAPanel);
            title(app.RFGrafica_3, 'Fase')
            xlabel(app.RFGrafica_3, 'rad/muestra')
            ylabel(app.RFGrafica_3, 'Fase (rad)')
            zlabel(app.RFGrafica_3, 'Z')
            app.MagnitudButtonGroup = uibuttongroup(app.RESPUESTAENFRECUENCIAPanel);
            app.MagnitudButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MagnitudButtonGroupSelectionChanged, true);
            app.MagnitudButtonGroup.Title = 'Magnitud';
            app.EndBButton = uiradiobutton(app.MagnitudButtonGroup);
            app.EndBButton.Text = 'En dB';
            app.EndBButton.Value = true;
            app.NoendBButton = uiradiobutton(app.MagnitudButtonGroup);
            app.NoendBButton.Text = 'No en dB';
            app.FrecuenciaButtonGroup = uibuttongroup(app.RESPUESTAENFRECUENCIAPanel);
            app.FrecuenciaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FrecuenciaButtonGroupSelectionChanged, true);
            app.FrecuenciaButtonGroup.Title = 'Frecuencia';
            app.radmuestraButton = uiradiobutton(app.FrecuenciaButtonGroup);
            app.radmuestraButton.Text = 'rad/muestra';
            app.radmuestraButton.Value = true;
            app.HzButton = uiradiobutton(app.FrecuenciaButtonGroup);
            app.HzButton.Text = 'Hz';
            app.GUARDARPanel_2 = uipanel(app.INTERFAZ);
            app.GUARDARPanel_2.Title = 'GUARDAR';
            app.GUARDARcsvButton = uibutton(app.GUARDARPanel_2, 'push');
            app.GUARDARcsvButton.ButtonPushedFcn = createCallbackFcn(app, @GUARDARcsvButtonPushed, true);
            app.GUARDARcsvButton.Text = 'GUARDAR .csv';
            app.GuardarsealCargadayFiltradaLabel = uilabel(app.GUARDARPanel_2);
            app.GuardarsealCargadayFiltradaLabel.Text = 'Guardar señal Cargada y Filtrada';
            app.GuardarParametrosdeFiltroLabel = uilabel(app.GUARDARPanel_2);
            app.GuardarParametrosdeFiltroLabel.Text = 'Guardar Parametros de Filtro';
            app.GUARDARmatButton = uibutton(app.GUARDARPanel_2, 'push');
            app.GUARDARmatButton.ButtonPushedFcn = createCallbackFcn(app, @GUARDARmatButtonPushed, true);
            app.GUARDARmatButton.Text = 'GUARDAR .mat';
            app.TIVAC = uitab(app.TabGroup);
            app.TIVAC.Title = 'Tiva C';
            app.GUARDARPanel_3 = uipanel(app.TIVAC);
            app.GUARDARPanel_3.Title = 'GUARDAR';
            app.GuardarParametrosdeFiltroLabel_2 = uilabel(app.GUARDARPanel_3);
            app.GuardarParametrosdeFiltroLabel_2.Text = 'Guardar Parametros de Filtro';
            app.GUARDARmatButton_2 = uibutton(app.GUARDARPanel_3, 'push');
            app.GUARDARmatButton_2.ButtonPushedFcn = createCallbackFcn(app, @GUARDARmatButton_2Pushed, true);
            app.GUARDARmatButton_2.Text = 'GUARDAR .mat';
            app.CONEXINCONTIVACPanel = uipanel(app.TIVAC);
            app.CONEXINCONTIVACPanel.Title = 'CONEXIÓN CON TIVA C';
            app.Lamp_2 = uilamp(app.CONEXINCONTIVACPanel);
            app.ConexinEstablecidaLabel = uilabel(app.CONEXINCONTIVACPanel);
            app.ConexinEstablecidaLabel.Text = 'Conexión Establecida';
            app.CONECTARATIVACButton = uibutton(app.CONEXINCONTIVACPanel, 'push');
            app.CONECTARATIVACButton.ButtonPushedFcn = createCallbackFcn(app, @CONECTARATIVACButtonPushed, true);
            app.CONECTARATIVACButton.Text = 'CONECTAR A TIVA C';
            app.DISEODELFILTROPanel_2 = uipanel(app.TIVAC);
            app.DISEODELFILTROPanel_2.Title = 'DISEÑO DEL FILTRO';
            app.SubtipoDropDown_2Label = uilabel(app.DISEODELFILTROPanel_2);
            app.SubtipoDropDown_2Label.Text = 'Subtipo';
            app.SubtipoDropDown_2 = uidropdown(app.DISEODELFILTROPanel_2);
            app.SubtipoDropDown_2.Items = {'Butterworth', 'Chebyshev I', 'Chebyshev II'};
            app.SubtipoDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SubtipoDropDown_2ValueChanged, true);
            app.SubtipoDropDown_2.Value = 'Butterworth';
            app.ClaseDropDown_2Label = uilabel(app.DISEODELFILTROPanel_2);
            app.ClaseDropDown_2Label.Text = 'Clase';
            app.ClaseDropDown_2 = uidropdown(app.DISEODELFILTROPanel_2);
            app.ClaseDropDown_2.Items = {'Pasabajas', 'Pasalatas', 'Pasabandas', 'Eliminabandas'};
            app.ClaseDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ClaseDropDown_2ValueChanged, true);
            app.ClaseDropDown_2.Value = 'Pasabajas';
            app.FrecuenciadeCorteEditField_2 = uieditfield(app.DISEODELFILTROPanel_2, 'numeric');
            app.FrecuenciadeCorteEditField_2.Limits = [1 499];
            app.FrecuenciadeCorteEditField_2.RoundFractionalValues = 'on';
            app.FrecuenciadeCorteEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadeCorteEditField_2ValueChanged, true);
            app.FrecuenciadeCorteEditField_2.Value = 1;
            app.FrecuenciadeCorteEditField_2Label = uilabel(app.DISEODELFILTROPanel_2);
            app.FrecuenciadeCorteEditField_2Label.Text = 'Frecuencia de Corte';
            app.FrecuenciadeCorteBajaEditField_2Label = uilabel(app.DISEODELFILTROPanel_2);
            app.FrecuenciadeCorteBajaEditField_2Label.Text = 'Frecuencia de Corte Baja';
            app.FrecuenciadeCorteBajaEditField_2 = uieditfield(app.DISEODELFILTROPanel_2, 'numeric');
            app.FrecuenciadeCorteBajaEditField_2.Limits = [1 499];
            app.FrecuenciadeCorteBajaEditField_2.RoundFractionalValues = 'on';
            app.FrecuenciadeCorteBajaEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadeCorteBajaEditField_2ValueChanged, true);
            app.FrecuenciadeCorteBajaEditField_2.Value = 50;
            app.FrecuenciadeCorteAltaEditField_2Label = uilabel(app.DISEODELFILTROPanel_2);
            app.FrecuenciadeCorteAltaEditField_2Label.Text = 'Frecuencia de Corte Alta';
            app.FrecuenciadeCorteAltaEditField_2 = uieditfield(app.DISEODELFILTROPanel_2, 'numeric');
            app.FrecuenciadeCorteAltaEditField_2.Limits = [1 499];
            app.FrecuenciadeCorteAltaEditField_2.RoundFractionalValues = 'on';
            app.FrecuenciadeCorteAltaEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadeCorteAltaEditField_2ValueChanged, true);
            app.FrecuenciadeCorteAltaEditField_2.Value = 100;
            app.StopbandAttenuationRsEditField_2Label = uilabel(app.DISEODELFILTROPanel_2);
            app.StopbandAttenuationRsEditField_2Label.Text = ' Stopband Attenuation (Rs)';
            app.StopbandAttenuationRsEditField_2 = uieditfield(app.DISEODELFILTROPanel_2, 'numeric');
            app.StopbandAttenuationRsEditField_2.Limits = [1 5000];
            app.StopbandAttenuationRsEditField_2.ValueChangedFcn = createCallbackFcn(app, @StopbandAttenuationRsEditField_2ValueChanged, true);
            app.StopbandAttenuationRsEditField_2.Value = 1;
            app.PassbandRippleRpEditField_2Label = uilabel(app.DISEODELFILTROPanel_2);
            app.PassbandRippleRpEditField_2Label.Text = ' Passband Ripple (Rp)';
            app.PassbandRippleRpEditField_2 = uieditfield(app.DISEODELFILTROPanel_2, 'numeric');
            app.PassbandRippleRpEditField_2.Limits = [0.1 5000];
            app.PassbandRippleRpEditField_2.ValueChangedFcn = createCallbackFcn(app, @PassbandRippleRpEditField_2ValueChanged, true);
            app.PassbandRippleRpEditField_2.Value = 0.1;
            app.HzLabel_4 = uilabel(app.DISEODELFILTROPanel_2);
            app.HzLabel_4.Text = 'Hz';
            app.HzLabel_5 = uilabel(app.DISEODELFILTROPanel_2);
            app.HzLabel_5.Text = 'Hz';
            app.HzLabel_6 = uilabel(app.DISEODELFILTROPanel_2);
            app.HzLabel_6.Text = 'Hz';
            app.dBLabel_3 = uilabel(app.DISEODELFILTROPanel_2);
            app.dBLabel_3.Text = 'dB';
            app.dBLabel_4 = uilabel(app.DISEODELFILTROPanel_2);
            app.dBLabel_4.Text = 'dB';
            app.ENVIARATIVACButton = uibutton(app.DISEODELFILTROPanel_2, 'push');
            app.ENVIARATIVACButton.ButtonPushedFcn = createCallbackFcn(app, @ENVIARATIVACButtonPushed, true);
            app.ENVIARATIVACButton.Text = 'ENVIAR A TIVA C';
            app.nicamenteTipodeFiltroIIRLabel = uilabel(app.DISEODELFILTROPanel_2);
            app.nicamenteTipodeFiltroIIRLabel.Text = '*Únicamente Tipo de Filtro IIR';
            app.OrdenButtonGroup = uibuttongroup(app.DISEODELFILTROPanel_2);
            app.OrdenButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OrdenButtonGroupSelectionChanged, true);
            app.OrdenButtonGroup.Title = 'Orden';
            app.Button = uiradiobutton(app.OrdenButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.OrdenButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.OrdenButtonGroup);
            app.Button_3.Text = '3';
            app.Button_4 = uiradiobutton(app.OrdenButtonGroup);
            app.Button_4.Text = '4';
            app.RESPUESTAENFRECUENCIAPanel_2 = uipanel(app.TIVAC);
            app.RESPUESTAENFRECUENCIAPanel_2.Title = 'RESPUESTA EN FRECUENCIA';
            app.RFGrafica_2 = uiaxes(app.RESPUESTAENFRECUENCIAPanel_2);
            title(app.RFGrafica_2, 'Respuesta en Frecuencia del Filtro')
            xlabel(app.RFGrafica_2, 'rad/muestra')
            ylabel(app.RFGrafica_2, 'Magnitud en dB')
            zlabel(app.RFGrafica_2, 'Z')
            app.RFGrafica_4 = uiaxes(app.RESPUESTAENFRECUENCIAPanel_2);
            title(app.RFGrafica_4, 'Fase')
            xlabel(app.RFGrafica_4, 'rad/muestra')
            ylabel(app.RFGrafica_4, 'Fase (rad)')
            zlabel(app.RFGrafica_4, 'Z')
            app.MagnitudButtonGroup_2 = uibuttongroup(app.RESPUESTAENFRECUENCIAPanel_2);
            app.MagnitudButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @MagnitudButtonGroup_2SelectionChanged, true);
            app.MagnitudButtonGroup_2.Title = 'Magnitud';
            app.EndBButton_2 = uiradiobutton(app.MagnitudButtonGroup_2);
            app.EndBButton_2.Text = 'En dB';
            app.EndBButton_2.Value = true;
            app.NoendBButton_2 = uiradiobutton(app.MagnitudButtonGroup_2);
            app.NoendBButton_2.Text = 'No en dB';
            app.FrecuenciaButtonGroup_2 = uibuttongroup(app.RESPUESTAENFRECUENCIAPanel_2);
            app.FrecuenciaButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @FrecuenciaButtonGroup_2SelectionChanged, true);
            app.FrecuenciaButtonGroup_2.Title = 'Frecuencia';
            app.radmuestraButton_2 = uiradiobutton(app.FrecuenciaButtonGroup_2);
            app.radmuestraButton_2.Text = 'rad/muestra';
            app.radmuestraButton_2.Value = true;
            app.HzButton_2 = uiradiobutton(app.FrecuenciaButtonGroup_2);
            app.HzButton_2.Text = 'Hz';
            app.COEFICIENTESPanel = uipanel(app.TIVAC);
            app.COEFICIENTESPanel.Title = 'COEFICIENTES';
            app.aEditFieldLabel = uilabel(app.COEFICIENTESPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.COEFICIENTESPanel, 'text');
            app.bEditFieldLabel = uilabel(app.COEFICIENTESPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.COEFICIENTESPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end